classdef manual_volume_register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        LoadMenu           matlab.ui.container.Menu
        SaveMenu           matlab.ui.container.Menu
        UnloadMenu         matlab.ui.container.Menu
        ExitMenu           matlab.ui.container.Menu
        HelpMenu           matlab.ui.container.Menu
        UserGuideMenu      matlab.ui.container.Menu
        ShortKeysMenu      matlab.ui.container.Menu
        AboutMenu          matlab.ui.container.Menu
        PropertiesLabel    matlab.ui.control.Label
        GridLayout         matlab.ui.container.GridLayout
        ModeButtonGroup    matlab.ui.container.ButtonGroup
        ZProjectButton     matlab.ui.control.ToggleButton
        RegisterButton     matlab.ui.control.ToggleButton
        ViewButton         matlab.ui.control.ToggleButton
        BackZSpinner       matlab.ui.control.Spinner
        BackZSpinnerLabel  matlab.ui.control.Label
        FixedZIDLabel      matlab.ui.control.Label
        FixButton          matlab.ui.control.Button
        ForeZSpinner       matlab.ui.control.Spinner
        ForeZSpinnerLabel  matlab.ui.control.Label
        AlphaSlider        matlab.ui.control.Slider
        AlphaSliderLabel   matlab.ui.control.Label
        YSlider            matlab.ui.control.Slider
        YSliderLabel       matlab.ui.control.Label
        XSlider            matlab.ui.control.Slider
        XSliderLabel       matlab.ui.control.Label
        AngleKnob          matlab.ui.control.Knob
        AngleKnobLabel     matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        fileName;
        fileDir;
        time;

        stackData;      % x*y*z image stack(Original pixels)
        transData;      % linearly transferred from the original data, for visualization

        h_UIAxes;       % handle: uiaxes

        sizeX;          % X pixel number
        sizeY;          % Y pixel number
        sizeZ;          % Z plane number

        xShift;         % image shift x_shift pixels in x direction
        yShift;         % image shift y_shift pixels in y direction
        angleShift;     % image circle angle_shift around the center of the image
        alpha;          % the transparency of fore image

        foreZID;        % the current z plane
        backZID;        % the background z plane
        workMode;       % 0: No file loaded, 1:View, 2:Register, 3:Z Project
        FixedZID;       % the id of the fixed z plane, 0 when unfixed

        shiftNote;      % to save the shift history

    end


    methods (Access = private)
%% Initialize

        % Initialize all components and factors
        function results = init_all(app)
            init_factors(app);
            init_components(app);
            functionlock(app);
        end

        function results = init_components(app)
            app.UIAxes.Title.String = 'Image Unloaded';
            app.XSlider.Value = 0;
            app.YSlider.Value = 0;
            app.AlphaSlider.Value = 50;
            app.AngleKnob.Value = 0;

            app.ForeZSpinner.Step = 1;
            app.ForeZSpinner.Value = 0;
            app.BackZSpinner.Step = 1;
            app.BackZSpinner.Value = 0;

            app.ModeButtonGroup.SelectedObject = app.ViewButton;
            app.PropertiesLabel.Text = '';
        end

        function results = init_factors(app)
            app.fileName = [];
            app.fileDir = [];
            app.time = [];
            app.stackData = [];
            app.sizeX = 0;
            app.sizeY = 0;
            app.sizeZ = 0;
            app.workMode = 0;
            app.FixedZID = 0;

            app.xShift = 0;
            app.yShift = 0;
            app.alpha = 50;
            app.angleShift = 0;

            app.foreZID = 0;
            app.backZID = 0;
            app.shiftNote = 0;

            app.h_UIAxes = app.UIAxes;

        end

%% Access Control

        function results = functionlock(app)

            switch app.workMode
                case 0
                    app.SaveMenu.Enable = false;
                    app.UnloadMenu.Enable = false;

                    app.BackZSpinner.Enable = false;
                    app.FixButton.Enable = false;
                    app.ForeZSpinner.Enable = false;

                    app.AlphaSlider.Enable = false;
                    app.YSlider.Enable = false;
                    app.XSlider.Enable = false;
                    app.AngleKnob.Enable = false;

                    app.ViewButton.Enable = false;
                    app.RegisterButton.Enable = false;
                    app.ZProjectButton.Enable = false;

                case 1
                    app.SaveMenu.Enable = true;
                    app.UnloadMenu.Enable = true;

                    app.ForeZSpinner.Enable = true;
                    app.FixButton.Enable = true;
                    app.BackZSpinner.Enable = false;

                    app.AlphaSlider.Enable = false;
                    app.YSlider.Enable = false;
                    app.XSlider.Enable = false;
                    app.AngleKnob.Enable = false;

                    app.ViewButton.Enable = true;
                    app.RegisterButton.Enable = true;
                    app.ZProjectButton.Enable = true;
                    

                case 2
                    app.SaveMenu.Enable = true;
                    app.UnloadMenu.Enable = true;

                    app.ForeZSpinner.Enable = true;
                    app.FixButton.Enable = true;
                    app.BackZSpinner.Enable = true;

                    app.AlphaSlider.Enable = true;
                    app.YSlider.Enable = true;
                    app.XSlider.Enable = true;
                    app.AngleKnob.Enable = true;

                    app.ViewButton.Enable = true;
                    app.RegisterButton.Enable = true;
                    app.ZProjectButton.Enable = true;

                case 3
                    app.SaveMenu.Enable = true;
                    app.UnloadMenu.Enable = true;

                    app.ForeZSpinner.Enable = true;
                    app.FixButton.Enable = false;
                    app.BackZSpinner.Enable = false;

                    app.AlphaSlider.Enable = false;
                    app.YSlider.Enable = false;
                    app.XSlider.Enable = false;
                    app.AngleKnob.Enable = false;

                    app.ViewButton.Enable = true;
                    app.RegisterButton.Enable = true;
                    app.ZProjectButton.Enable = true;
                    
                otherwise
                    warndlg('WorkMode Error', 'Bug');
            end
            
        end


%% Loading

        % Load a 3D image(Modified from getbfmovieinfo.m Ver 1.20 by JXH)
        function loadingResult = loadimage(app, movFile)
            % Input: (FileDir) + FileName
            % Output: Loading result: 0,Failed; 1, Succeeded

            data = bfopen(movFile);

            metaData = data{1,4};
            app.sizeX = metaData.getPixelsSizeX(0).getValue();
            app.sizeY = metaData.getPixelsSizeY(0).getValue();
            app.sizeZ = metaData.getPixelsSizeZ(0).getValue();
            sizeC = metaData.getPixelsSizeC(0).getValue();
            sizeT = metaData.getPixelsSizeT(0).getValue();

            if sizeT * sizeC == 1 % ensure that it is a 3-D stack.
                
                dataCell = reshape(data{1,1}(:,1), [1, app.sizeZ]);
                dataMatTemp = cell2mat(dataCell);
                movData = reshape(dataMatTemp, [app.sizeX, app.sizeY, ...
                    app.sizeZ]); % movData:x*y*z matrix
                
                splited = split(movFile,'.');
                format = splited{end};

                if strcmp(format,'ims')
                    metaData = data{1,2};
                    F = 'yyyy-MM-dd HH:mm:ss.SSS';
                    app.time = datetime(metaData.get('Global TimePoint1'), ...
                        'InputFormat', F, 'Format', F);
                end

                movData = flip(movData, 1); % flip the image upside down
                app.stackData = movData;

                loadingResult = 1;

                % transfer the data into [0,1](for better use in a RGB channel)
                minPixel = min(double(app.stackData(:)));
                maxPixel = max(double(app.stackData(:)));
                app.transData = double(app.stackData-minPixel)/(maxPixel-minPixel);

%                 coder.varsize('app.transData', [ceil(app.sizeX*sqrt(2)), ...
%                     ceil(app.sizeY*sqrt(2)), app.sizeZ], [1 1 0]);
%                 % make the size of transData changable(for rotation)


            else
                warndlg('4/5D images unsupported yet.', 'Warning');
                loadingResult = 0;
            end


            return 
        end


%% Draw images in the UIAxes
        function results = drawimage(app)

            % sizeX*sizeY zeros, for an empty channel when making a RGB image
            zeroPlane = zeros(app.sizeX, app.sizeY);


            switch app.workMode
                case 1 % View Mode
                    % Convert the image into a 3D RGB stack                    
                    showImage = cat(3,zeroPlane, app.transData(:,:,app.foreZID), zeroPlane);
                    imagesc(app.UIAxes, showImage);

%                     im_H = image(app.UIAxes_Main,...
%                 [1 round(app.images_info.width*app.viewing_scale)],...
%                 [1 round(app.images_info.height*app.viewing_scale)],...
%                 app.mov_frames(:,:,1));

                case 2 % Register Mode
                    showImageFore = cat(3, app.transData(:,:,app.foreZID), ...
                         zeroPlane, zeroPlane);
                    showImageBack = cat(3, zeroPlane, ...
                         app.transData(:,:,app.backZID), zeroPlane);

                    imagesc(app.UIAxes, showImageBack);
                    hold(app.h_UIAxes);
                    handleForeImage = imagesc(app.UIAxes, showImageFore);
                    hold(app.h_UIAxes);

                    set(handleForeImage, 'AlphaData', app.alpha/100);              


                case 3 % Z Project Mode
                    zProjectData = max(app.transData, [], 3);
                    imagesc(app.UIAxes, zProjectData);
                    app.UIAxes.XLim = [0 app.sizeX];
                    app.UIAxes.YLim = [0 app.sizeY];

                otherwise
                    warndlg('Aha, a bug.', 'BUG');
            end       
        end


        function results = resetShifts(app)
            app.xShift = 0;
            app.yShift = 0;
            app.angleShift = 0;
            app.XSlider.Value = 0;
            app.XSlider.Limits = [-20,20];
            app.YSlider.Value = 0;
            app.YSlider.Limits = [-20,20];
            app.AngleKnob.Value = 0;
        end
        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init_all();
        end

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)
            app.alpha = app.AlphaSlider.Value;
            app.drawimage();
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            xRange = app.XSlider.Limits(2) - app.XSlider.Limits(1);
            % If the slider reach its limits, change the limits
            if app.XSlider.Value == app.XSlider.Limits(1)
                app.XSlider.Limits =  app.XSlider.Limits - xRange/4;
            elseif app.XSlider.Value == app.XSlider.Limits(2)
                app.XSlider.Limits =  app.XSlider.Limits + xRange/4;
            end

            deltaXShift = round(app.XSlider.Value - app.xShift);            
            app.xShift = app.XSlider.Value;
            app.transData(:,:,app.foreZID) = circshift(app.transData(:,:,app.foreZID), deltaXShift, 2);
            app.shiftNote(app.foreZID, 1) = app.shiftNote(app.foreZID, 1) + deltaXShift;
            app.stackData(:,:,app.foreZID) = circshift(app.stackData(:,:,app.foreZID), deltaXShift, 2);
            app.drawimage();
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            yRange = app.YSlider.Limits(2) - app.YSlider.Limits(1);
            if app.YSlider.Value == app.YSlider.Limits(1)
                app.YSlider.Limits =  app.YSlider.Limits - yRange/4;
            elseif app.YSlider.Value == app.YSlider.Limits(2)
                app.YSlider.Limits =  app.YSlider.Limits + yRange/4;
            end

            deltaYShift = round(- app.YSlider.Value - app.yShift);
            app.yShift = - app.YSlider.Value;
            app.transData(:,:,app.foreZID) = circshift(app.transData(:,:,app.foreZID), deltaYShift, 1);
            app.shiftNote(app.foreZID, 2) = app.shiftNote(app.foreZID, 2) + deltaYShift;
            app.stackData(:,:,app.foreZID) = circshift(app.stackData(:,:,app.foreZID), deltaYShift, 1);
            app.drawimage();
        end

        % Value changed function: AngleKnob
        function AngleKnobValueChanged(app, event)
            deltaAngle = - app.AngleKnob.Value - app.angleShift;
            app.angleShift = - app.AngleKnob.Value;
            app.transData(:,:,app.foreZID) = imrotate(app.transData(:,:,app.foreZID), deltaAngle, 'crop');
%             app.stackData(:,:,app.foreZID) = imrotate(app.stackData(:,:,app.foreZID), deltaAngle);
            app.drawimage();
        end

        % Value changed function: ForeZSpinner
        function ForeZSpinnerValueChanged(app, event)
            app.foreZID = app.ForeZSpinner.Value;
            app.resetShifts();
            app.drawimage();
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [name,path] = uigetfile('*.ims');
            if ~isequal(name,0)
                app.fileName = name;
                app.fileDir = path;
                app.UIAxes.Title.String = name;
                loadingResult = app.loadimage([path,name]);
                disp('Loading succeeded.');

                if loadingResult == 1

                    app.ForeZSpinner.Value = 1; % start from the first z plane, zID = 1
                    app.foreZID = 1;
                    app.ForeZSpinner.Limits = [1 app.sizeZ];
                    app.BackZSpinner.Limits = [1 app.sizeZ];
                    app.UIAxes.XLim = [0 app.sizeX];
                    app.UIAxes.YLim = [0 app.sizeY];
                    app.workMode = 1;
                    app.shiftNote = zeros(app.sizeZ, 2);
                    app.functionlock();
                    app.drawimage();
                    
                else
                    warndlg('Loading failed.', 'Warning');
                end

                
            else
                warndlg('No File Selected', 'Warning');
            end
            
        end

        % Button pushed function: FixButton
        function FixButtonPushed(app, event)
            app.FixedZID = app.ForeZSpinner.Value;
            app.FixedZIDLabel.Text = ['Fixed Z ID: ', num2str(app.FixedZID)];
        end

        % Callback function
        function ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
            switch selectedButton
                case app.ViewButton
                    app.workMode = 1; % View Mode
                    functionlock(app);
                case app.RegisterButton
                    app.workMode = 2; % Register Mode
                    functionlock(app);
                    if app.backZID == 0
                        app.BackZSpinner.Value = 1;
                        app.backZID = 1;
                    end
                case app.ZProjectButton
                    app.workMode = 3; % Z Projection Mode
                    functionlock(app);
            end

            app.drawimage(); % Draw right after buttonswitch
        end

        % Value changed function: BackZSpinner
        function BackZSpinnerValueChanged(app, event)
            app.backZID = app.BackZSpinner.Value;
            app.drawimage();
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [filename, path] = uiputfile('*.tif', 'Save', [app.fileName, '_Registered']);
            
            if file != 0
                for z = 1: app.sizeZ
                    imwrite(mat2gray(app.stackData(:,:,z)), [path, filename], ...
                        'tif', 'WriteMode', 'append');
                end
                shiftNote = app.shiftNote;
                save([path, app.fileName, '_RegisterNote'],'shiftNote','-mat'); 
                msgbox('Saving Completed.');
            else
                return;
            end

        end

        % Menu selected function: UnloadMenu
        function UnloadMenuSelected(app, event)
            app.init_all();
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            exit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.UnloadMenu = uimenu(app.FileMenu);
            app.UnloadMenu.MenuSelectedFcn = createCallbackFcn(app, @UnloadMenuSelected, true);
            app.UnloadMenu.Text = 'Unload';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UserGuideMenu = uimenu(app.HelpMenu);
            app.UserGuideMenu.Text = 'UserGuide';
            app.ShortKeysMenu = uimenu(app.HelpMenu);
            app.ShortKeysMenu.Text = 'Short Keys';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'ImageName')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.AngleKnobLabel = uilabel(app.GridLayout);
            app.AngleKnobLabel.Text = 'Angle';
            app.AngleKnob = uiknob(app.GridLayout, 'continuous');
            app.AngleKnob.Limits = [-50 50];
            app.AngleKnob.ValueChangedFcn = createCallbackFcn(app, @AngleKnobValueChanged, true);
            app.XSliderLabel = uilabel(app.GridLayout);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.GridLayout);
            app.XSlider.Limits = [-20 20];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YSliderLabel = uilabel(app.GridLayout);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.GridLayout);
            app.YSlider.Limits = [-20 20];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.AlphaSliderLabel = uilabel(app.GridLayout);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.GridLayout);
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.AlphaSlider.Value = 50;
            app.ForeZSpinnerLabel = uilabel(app.GridLayout);
            app.ForeZSpinnerLabel.Text = 'ForeZ';
            app.ForeZSpinner = uispinner(app.GridLayout);
            app.ForeZSpinner.ValueChangedFcn = createCallbackFcn(app, @ForeZSpinnerValueChanged, true);
            app.FixButton = uibutton(app.GridLayout, 'push');
            app.FixButton.ButtonPushedFcn = createCallbackFcn(app, @FixButtonPushed, true);
            app.FixButton.Text = 'Fix';
            app.FixedZIDLabel = uilabel(app.GridLayout);
            app.FixedZIDLabel.Text = 'Fixed Z ID:';
            app.BackZSpinnerLabel = uilabel(app.GridLayout);
            app.BackZSpinnerLabel.Text = 'BackZ';
            app.BackZSpinner = uispinner(app.GridLayout);
            app.BackZSpinner.ValueChangedFcn = createCallbackFcn(app, @BackZSpinnerValueChanged, true);
            app.ModeButtonGroup = uibuttongroup(app.GridLayout);
            app.ModeButtonGroup.Title = 'Mode';
            app.ViewButton = uitogglebutton(app.ModeButtonGroup);
            app.ViewButton.Text = 'View';
            app.ViewButton.Value = true;
            app.RegisterButton = uitogglebutton(app.ModeButtonGroup);
            app.RegisterButton.Text = 'Register';
            app.ZProjectButton = uitogglebutton(app.ModeButtonGroup);
            app.ZProjectButton.Text = 'ZProject';
            app.PropertiesLabel = uilabel(app.UIFigure);
            app.PropertiesLabel.Text = 'Properties';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_volume_register

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end